classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        EncodeTab              matlab.ui.container.Tab
        InputEditFieldLabel    matlab.ui.control.Label
        input                  matlab.ui.control.EditField
        EfficiencyXXxxxxLabel  matlab.ui.control.Label
        CompressionAlgorithmDropDownLabel  matlab.ui.control.Label
        modeSelect             matlab.ui.control.DropDown
        RunButton              matlab.ui.control.Button
        OutputLabel            matlab.ui.control.Label
        DecodeTab              matlab.ui.container.Tab
    end


    methods (Access = private)

        function coded = lempelZivEnco(app, src)
            % Lempel-Ziv
            % Steps:
            % 1. Identify unique sequences
            %       1.5 Assign a binary number to each symbol

            % 2. Assign (Prefix index, Suffix) [in binary] pairs to
            %    said sequences.
            %       2.1 Get prefix binary
            %       2.2 Get suffix binary

            % 3. Append both parts of the pair to make a larger binary
            %    number.

            % 4. For each pair, find the maximum possible value of the
            %    number the pair makes.

            % 5. Find the number of bits needed to represent that
            %    number.

            % 6. The code for each symbol is the binary pair we made
            % earlier.

            uniqueSeqArr = getUniqueSeq(app, src);

            uniqueSymbols = unique(src);

            pairs = getPrefixSuffixPair(app, uniqueSeqArr, uniqueSymbols);

            maxPairs = getMaxPairs(app, uniqueSeqArr, uniqueSymbols);
        end

        function dict = getUniqueSeq(~, src)
            % Turns our input string into an array of unique sequences.
            dict = []; % This will keep hold of our unique sequences.
            letterIDX = 1;
            while letterIDX <= length(src)

                seqEndIDX = letterIDX; % Init

                % We use cell2mat because extractBetween returns a cell
                % array instead of a normal char vector.
                seq = cell2mat(extractBetween(src, letterIDX, seqEndIDX));

                while ismember(seq, dict)
                    if(seqEndIDX == length(src))
                        break
                    end
                    seqEndIDX = seqEndIDX + 1;
                    seq = cell2mat(extractBetween(src, letterIDX, seqEndIDX));
                end

                dict = [dict, convertCharsToStrings(seq)];
                letterIDX = seqEndIDX+1;
            end
        end


        function pairs = getPrefixSuffixPair(~, seqArr, symbols)
            pairs = zeros(1, length(seqArr));
            for idx = 1:length(seqArr)

                seq = char(seqArr(idx));
                suffix = seq(end);

                % We need to find the location of the prefix in our
                % dictionary.

                if strlength(seq) == 1
                    prefixBin = 0;
                else
                    prefix = seq(1:end-1);
                    prefixBin = dec2bin(find(seqArr == prefix));
                end


                % When we try to find a letter in the uniques array
                % we get an integer. Turn that integer to binary and voila.
                suffixBin = pad(dec2bin(strfind(symbols, suffix)-1), ...
                    ceil(log2(numel(symbols))), "left", '0');


                pairs(idx) = convertCharsToStrings(strcat(num2str(prefixBin), num2str(suffixBin)));
            end

        end

        function maxPairs = getMaxPairs(~, seqs, uniqueSyms)
            noSyms = numel(seqs);
            maxPairs = zeros(1, noSyms);
            maxSym = length(uniqueSyms);
            for idx = 1:length(maxPairs)
                
            end
        end
        
        %adaptive arathmatic function encode
        function encoded_str = Adaptive_Arithmatic_Encode(app,str)


            len = length(str);          % length of the string

            u = unique(str);            % get unique characters from the string

            len_unique = length(u);     % length of the unique characters string

  
% General lookup table

            p = zeros(1, len_unique);
  
            for i = 1 : len_unique          %equalprob
                 p(i) = 1 / len_unique;
            end

  
% Encoder Table
  
            low = 0;
            high = 1;
            stage = zeros(1, len_unique + 1);   %table
            stage(1)=low;
            stage(length(stage))=high;

            for i = 2: length(stage)-1

                stage(i)= stage(i-1)+p(i);
    
            end

            u = sort(u);
            prev_observation = zeros(1, len_unique);
            p_sep = zeros(1, len_unique);

            for i = 1 : len
                for j = 1 : len_unique
  
       % if the value from 'str'
       % matches with 'u' then
                    if str(i) == u(j)
                        prev_observation(j)=prev_observation(j) + 1;
           
                        for k = 1 : len_unique 
                             p_sep(k)=(1+prev_observation(k))/(i+len_unique);
                        end
           
                        %pos = j;       %for debug
           
                        low = stage(j);
                        high = stage(j+1);
           
                        stage(1)=low;
                        stage(length(stage))=high;
                        for ind = 2: length(stage)-1
          
                            stage(ind)= stage(ind-1)+p_sep(ind-1)*(high - low);
    
              
                         end
 
                        break
       
                    end
   
                end

            end
  

% displaying tag value

l_of_bin = ceil(log2(1/(high-low)))+1;

tag = (low + high)/2;

encoded_str = '';
%         for i=1:l_of_bin
%             
%             tag = tag * 2;
%             
%             if tag < 1
%                 encoded_str = [encoded_str '0'];
%             else
%                 encoded_str = [encoded_str '1'];
%                 tag = tag - 1;
%             end
%             
%             
%         end
         a = fi((tag),1,l_of_bin,l_of_bin);
         encoded_str = a.bin;


%encoded_str = tag;
        end
        
        %adaptive arathmatic function encode
        function str = Adaptive_Arithmatic_Decode(app,str,len,symbol)
        
        %%decoding
        decoded_str=0;
        encoded_str = convertStringsToChars(string(str));
        tester = encoded_str;
        tester = convertStringsToChars(tester);
        Output=char(num2cell(tester));
        Output=reshape(str2num(Output),1,[]) ;
        decoded_str = Output*2.^(-1:-1:-length(Output)).' ;

        tag = decoded_str;

        
        u = sort(symbol);
        for i = 1 : length(u)          %equalprob
               p(i) = 1 / length(u);
        end

        low = 0;

        high = 1;


        stage = zeros(1, length(u) + 1);   %table

        stage(1)=low;
        stage(length(stage))=high;

        for i = 2: length(stage)-1
            stage(i)= stage(i-1)+p(i);
        end

       
        prev_observation = zeros(1, length(u));
        p_sep = zeros(1, length(u));

        str = '';
  
        for i = 1 : len
             for j = 1 : length(u)
       
       % If tag value falls between a certain 
       % range in lookup table then
                if tag > stage(j) && tag < stage(j+1)
                    prev_observation(j)=prev_observation(j) + 1;
           %if i ~= 1
                     for k = 1 : length(u) 
                         p_sep(k)=(1+prev_observation(k))/(i+length(u));
                     end
           %end
           %total_previous_observation_String = total_previous_observation_String + 1;


                     pos = j;
                     display(pos);
                     disp(u(j));
           
                    low = stage(j);
                    high = stage(j+1);
           
                    stage(1)=low;
                    stage(length(stage))=high;
                    for ind = 2: length(stage)-1

                        stage(ind)= stage(ind-1)+p_sep(ind-1)*(high - low);
                    end
  
           % Geeting the matched tag 
           % value character

                    decoded_str = u(pos);
           
           %if decoded_str == ' '
                     str = [str decoded_str];
           
           
           %disp(decoded_str);
  
           % String concatenating 
           % 'decoded_str' into 'str'
           %str = strcat(str, decoded_str);
            
                    break
       
                end
   
             end


        end
  
% Displaying final decoded string
%disp(str)


        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            src = app.input.Value; % Input source text

            if app.modeSelect.Value == "Adaptive Arithmetic Coding"
                % Add ADA ari here
               % uialert(app.UIFigure,'Adaptive Arithmetic Here',"MSG");
                app.OutputLabel.Text = app.Adaptive_Arithmatic_Encode(src);
                %app.OutputLabel.Text = app.Adaptive_Arithmatic_Decode(src,5,['A' 'B']);
            
               
            else
                app.OutputLabel.Text = app.lempelZivEnco(src);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EncodeTab = uitab(app.TabGroup);
            app.EncodeTab.Title = 'Encode';
            app.InputEditFieldLabel = uilabel(app.EncodeTab);
            app.InputEditFieldLabel.Text = {'Input'; ''};
            app.input = uieditfield(app.EncodeTab, 'text');
            app.input.Value = 'abacbaccbaaccbbacbabca';
            app.EfficiencyXXxxxxLabel = uilabel(app.EncodeTab);
            app.EfficiencyXXxxxxLabel.Text = 'Efficiency = XX.xxxx%';
            app.CompressionAlgorithmDropDownLabel = uilabel(app.EncodeTab);
            app.CompressionAlgorithmDropDownLabel.Text = 'Compression Algorithm';
            app.modeSelect = uidropdown(app.EncodeTab);
            app.modeSelect.Items = {'Adaptive Arithmetic Coding', 'Lempel-Ziv Coding'};
            app.modeSelect.Value = 'Lempel-Ziv Coding';
            app.RunButton = uibutton(app.EncodeTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.OutputLabel = uilabel(app.EncodeTab);
            app.OutputLabel.Text = 'Output:';
            app.DecodeTab = uitab(app.TabGroup);
            app.DecodeTab.Title = 'Decode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end